classdef Covid_data_visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                matlab.ui.control.RadioButton
        CumulativeButton           matlab.ui.control.RadioButton
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        BothButton                 matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        CasesButton                matlab.ui.control.RadioButton
        StateorRegionListBox       matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        CountryListBoxLabel        matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Countries
        TimePoints
        CountryNames
        CurrCountryIdx
        CurrStateIdx
        ShowCumulative
        ShowCases
        ShowDeaths
        AverageDays
    end
    
    methods (Access = private)
        function plotData(app)
            cla(app.UIAxes, 'reset');
            
            if app.ShowCumulative
                plot_title = "Cumulative Number of ";

                if app.ShowCases
                    if app.ShowDeaths
                        yyaxis(app.UIAxes, 'left');
                        plot_title = plot_title + "Cases and Deaths";
                    else
                        plot_title = plot_title + "Cases";
                    end
                    yaxis_values = movmean(app.Countries(app.CurrCountryIdx).States(app.CurrStateIdx).CumulativeCases, app.AverageDays);
                    bar(app.UIAxes, app.TimePoints, yaxis_values);
                end

                if app.ShowDeaths
                    if app.ShowCases
                        yyaxis(app.UIAxes, 'right');
                    else
                        plot_title = plot_title + "Deaths";
                    end
                    yaxis_values = movmean(app.Countries(app.CurrCountryIdx).States(app.CurrStateIdx).CumulativeDeaths, app.AverageDays);
                    plot(app.UIAxes, app.TimePoints, yaxis_values, "r");
                end
                
            else
                plot_title = "Daily Number of ";
                if app.ShowCases
                    if app.ShowDeaths
                        yyaxis(app.UIAxes, 'left');
                        plot_title = plot_title + "Cases and Deaths";
                    else
                        plot_title = plot_title + "Cases";
                    end
                    yaxis_values = movmean(app.Countries(app.CurrCountryIdx).States(app.CurrStateIdx).DailyCases, app.AverageDays);
                    bar(app.UIAxes, app.TimePoints, yaxis_values);
                end

                if app.ShowDeaths
                    if app.ShowCases
                        yyaxis(app.UIAxes, 'right');
                    else
                        plot_title = plot_title + "Deaths";
                    end
                    yaxis_values = movmean(app.Countries(app.CurrCountryIdx).States(app.CurrStateIdx).DailyDeaths, app.AverageDays);
                    plot(app.UIAxes, app.TimePoints, yaxis_values, "r")
                end
            end

            if app.CurrCountryIdx == 1
                plot_title = plot_title + " Globally";
            else
                plot_title = plot_title + " in ";
                if app.CurrStateIdx == 1
                    plot_title = plot_title + app.CountryNames(app.CurrCountryIdx);
                else
                    plot_title = plot_title + app.Countries(app.CurrCountryIdx).StateNames(app.CurrStateIdx);
                end
            end

            if app.AverageDays > 1
                plot_title = plot_title + " (" + num2str(app.AverageDays) + "-day mean)";
            end

            app.UIAxes.Title.String = plot_title;
            app.UIAxes.XLim = [app.TimePoints(1)-21, app.TimePoints(end)+5];
            datetick(app.UIAxes, 'x', 'mmm yy', 'keeplimits');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Data From: https://coronavirus.jhu.edu/map.html 
            data_struct = load('covid_data.mat');
            data = data_struct.covid_data;

            [countries, index_countries, ~] = unique(data(2:end, 1), 'stable');
            states_raw = data(2:end, 2:end);
            countries_data = cell(length(countries), 2);
            global_data = states_raw(1, :);
            global_data{1, 1} = 'All';

            for ii = 1:length(countries)
                countries_data{ii, 1} = countries{ii};
                states_raw{index_countries(ii), 1} = 'All';
                if ii == length(countries)
                    countries_data{ii, 2} = states_raw(index_countries(ii):end, :);
                else
                    countries_data{ii, 2} = states_raw(index_countries(ii):index_countries(ii+1)-1, :);
                end
            
                if ii > 1
                    for jj = 1:size(countries_data{ii, 2}, 1)
                        for kk = 2:size(global_data, 2)
                            global_data{1, kk} = global_data{1, kk} + countries_data{ii, 2}{jj, kk};
                        end
                    end
                end
            end

            app.Countries = [Country('Global', global_data)];
            app.CountryNames = "Global";

            for ii = 1:size(countries_data, 1)
                app.Countries = [app.Countries, Country(countries_data{ii, 1}, countries_data{ii, 2})];
                app.CountryNames = [app.CountryNames, convertCharsToStrings(countries_data{ii, 1})];
            end

            app.TimePoints = [];
            for ii = 3:length(data(1, :))
                date_split = split(data{1, ii}, '/');
                app.TimePoints = [app.TimePoints, datetime(str2double(date_split{3}), str2double(date_split{1}), str2double(date_split{2}))];
            end
            
            % Default Values
            app.CurrCountryIdx = 1;
            app.CurrStateIdx = 1;
            app.ShowCases = true;
            app.ShowDeaths = false;
            app.ShowCumulative = true;
            app.AverageDays = 1;

            app.CountryListBox.Items = app.CountryNames;
            app.CountryListBox.Value = app.CountryNames(app.CurrCountryIdx);
            app.StateorRegionListBox.Items = app.Countries(app.CurrCountryIdx).StateNames;
            app.StateorRegionListBox.Value = app.Countries(app.CurrCountryIdx).StateNames(app.CurrStateIdx);
            
            app.DatatoPlotButtonGroup.SelectedObject = app.CasesButton;
            app.OptionButtonGroup.SelectedObject = app.CumulativeButton;
            app.AveragedofdaysSlider.Value = 1;

            plotData(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.CurrCountryIdx = find(app.CountryNames == event.Value);
            app.StateorRegionListBox.Items = app.Countries(app.CurrCountryIdx).StateNames;
            app.StateorRegionListBox.Value = app.Countries(app.CurrCountryIdx).StateNames(1);
            app.CurrStateIdx = 1;
            
            plotData(app);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.CurrStateIdx = find(app.Countries(app.CurrCountryIdx).StateNames == event.Value);

            plotData(app);
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.AverageDays = event.Value;
            app.AverageDays = round(app.AverageDays);
            app.AveragedofdaysSlider.Value = app.AverageDays;

            plotData(app);
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            switch event.NewValue
                case app.CasesButton
                    app.ShowCases = true;
                    app.ShowDeaths = false;
                case app.DeathsButton
                    app.ShowCases = false;
                    app.ShowDeaths = true;
                case app.BothButton
                    app.ShowCases = true;
                    app.ShowDeaths = true;
            end

            plotData(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            switch event.NewValue
                case app.CumulativeButton
                    app.ShowCumulative = true;
                case app.DailyButton
                    app.ShowCumulative = false;
            end

            plotData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Data Visualization (Data Source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.UIFigure);
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_data_visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end